classdef Control1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TextArea_2           matlab.ui.control.TextArea
        ListBox              matlab.ui.control.ListBox
        TextArea_3           matlab.ui.control.TextArea
        CheckSolutionButton  matlab.ui.control.Button
        Button               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: CheckSolutionButton
        function CheckSolutionButtonPushed(app, event)
            Control_Solution;
%           app.TextArea_2.Value = evalin('base','sol_text');
%           drawnow();
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.TextArea_2.Value = evalin('base','M_choice_Control');
            drawnow();
            assignin('base','pressed',0);
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            value = string(app.ListBox.Value);
            E = evalin('base','Control_index');
            answ = ["A.","B.","C.","D."];
            answ = answ(E);
            pressed = evalin('base','pressed');
            
            if strcmp(value,answ) && ~pressed
                app.TextArea_3.Value = "That is the correct answer !!";
                assignin('base','pressed',1);
                UpdateStats(4,1);
            elseif ~strcmp(value,answ) && ~pressed
                app.TextArea_3.Value = "That is the incorrect answer !";
                assignin('base','pressed',1);
                UpdateStats(4,0);
            elseif pressed
                app.TextArea_3.Value = 'You cannot choose again. View the solution or start over';
            end
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'A.', 'B.', 'C.', 'D.'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'A.';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.Editable = 'off';
            app.CheckSolutionButton = uibutton(app.UIFigure, 'push');
            app.CheckSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSolutionButtonPushed, true);
            app.CheckSolutionButton.Text = 'Check Solution';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Click here and then choose the correct answer below by clicking on the correct letter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Control1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end