classdef Login < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TextArea              matlab.ui.control.TextArea
        EditField             matlab.ui.control.EditField
        UsernameLabel         matlab.ui.control.Label
        EditField_2           matlab.ui.control.EditField
        PasswordLabel         matlab.ui.control.Label
        SigninButton          matlab.ui.control.Button
        EditField_3           matlab.ui.control.EditField
        UsernameLabel_2       matlab.ui.control.Label
        EditField_4           matlab.ui.control.EditField
        PasswordLabel_2       matlab.ui.control.Label
        SignupButton          matlab.ui.control.Button
        TextArea_2            matlab.ui.control.TextArea
        EditField_5           matlab.ui.control.EditField
        ReenterPasswordLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SigninButton
        function SigninButtonPushed(app, event)
            r_o_s = 1;
            Student_number = string(app.EditField.Value);
            assignin('base','Student_number',string(app.EditField.Value));
            password = string(app.EditField_2.Value);
            password2 = "";
            [pass,admin] = signup(r_o_s, Student_number, password, password2, app);
            if pass == 1
                mainScreen;
                app.UIFigure.Visible = 'off';
            elseif admin == 1
                Admin;
                app.UIFigure.Visible = 'off';
            end
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Value changed function: EditField_3
        function EditField_3ValueChanged(app, event)
            value = app.EditField_3.Value;
            
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            
        end

        % Button pushed function: SignupButton
        function SignupButtonPushed(app, event)
            r_o_s = 2;
            Student_number = string(app.EditField_3.Value);
            password = string(app.EditField_4.Value);
            password2 = string(app.EditField_5.Value);
            [~, ~] = signup(r_o_s, Student_number, password, password2, app);
        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            value = app.EditField_4.Value;
            
        end

        % Value changed function: EditField_5
        function EditField_5ValueChanged(app, event)
            value = app.EditField_5.Value;
            
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome, please log in either as an administrator or a student. If you do not have an account, you can create one.'};
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.UsernameLabel = uilabel(app.UIFigure);
            app.UsernameLabel.Text = 'Username';
            app.EditField_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.PasswordLabel = uilabel(app.UIFigure);
            app.PasswordLabel.Text = 'Password';
            app.SigninButton = uibutton(app.UIFigure, 'push');
            app.SigninButton.ButtonPushedFcn = createCallbackFcn(app, @SigninButtonPushed, true);
            app.SigninButton.Text = 'Sign in';
            app.EditField_3 = uieditfield(app.UIFigure, 'text');
            app.EditField_3.ValueChangedFcn = createCallbackFcn(app, @EditField_3ValueChanged, true);
            app.UsernameLabel_2 = uilabel(app.UIFigure);
            app.UsernameLabel_2.Text = 'Username';
            app.EditField_4 = uieditfield(app.UIFigure, 'text');
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.PasswordLabel_2 = uilabel(app.UIFigure);
            app.PasswordLabel_2.Text = 'Password';
            app.SignupButton = uibutton(app.UIFigure, 'push');
            app.SignupButton.ButtonPushedFcn = createCallbackFcn(app, @SignupButtonPushed, true);
            app.SignupButton.Text = 'Sign up';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'For passwords Do not use the "~" , "<" or ">" characters'};
            app.EditField_5 = uieditfield(app.UIFigure, 'text');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @EditField_5ValueChanged, true);
            app.ReenterPasswordLabel = uilabel(app.UIFigure);
            app.ReenterPasswordLabel.Text = 'Re-enter Password';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Login

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end