classdef av1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        TextArea_2           matlab.ui.control.TextArea
        ListBox              matlab.ui.control.ListBox
        TextArea_3           matlab.ui.control.TextArea
        CheckSolutionButton  matlab.ui.control.Button
        Button               matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, link)
            link = link;
        end

        % Button pushed function: CheckSolutionButton
        function CheckSolutionButtonPushed(app, event)
            av_Solution;
        end

        % Value changed function: TextArea_2
        function TextArea_2ValueChanged(app, event)
            value = app.TextArea_2.Value;
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            global number_of_links;
            global l_n;
            assignin('base','pressed',0);
            
            
            if number_of_links == 1
               app.TextArea_2.Value = evalin('base','M_choice_av1');
               drawnow(); 
            else
               if l_n == 1 
                   app.TextArea_2.Value = evalin('base','M_choice_av1');
                   drawnow();
               elseif l_n == 2 
                   app.TextArea_2.Value = evalin('base','M_choice_av2');
                   drawnow();
               else
                   app.TextArea_2.Value = evalin('base','M_choice_av3');
                   drawnow();
               end
            end
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            global number_of_links;
            global l_n;
            value = string(app.ListBox.Value);
            pressed = evalin('base','pressed');
            
            if number_of_links == 1
               E = evalin('base','av_index1');
            else
               if l_n == 1 
                   E = evalin('base','av_index1');
               elseif l_n == 2 
                   E = evalin('base','av_index2');
               else
                   E = evalin('base','av_index3');
               end
            end
            
            answ = ["A.","B.","C.","D."];
            answ = answ(E);
            
            if strcmp(value,answ) && ~pressed
                app.TextArea_3.Value = "That is the correct answer !!";
                assignin('base','pressed',1);
                UpdateStats(1,1);
            elseif ~strcmp(value,answ) && ~pressed
                app.TextArea_3.Value = "That is the incorrect answer !";
                assignin('base','pressed',1);
                UpdateStats(1,0);
            elseif pressed
                app.TextArea_3.Value = 'You cannot choose again. View the solution or start over';
            end
        end

        % Value changed function: TextArea_3
        function TextArea_3ValueChanged(app, event)
            value = app.TextArea_3.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TextArea_2 = uitextarea(app.UIFigure);
            app.TextArea_2.ValueChangedFcn = createCallbackFcn(app, @TextArea_2ValueChanged, true);
            app.TextArea_2.Editable = 'off';
            app.ListBox = uilistbox(app.UIFigure);
            app.ListBox.Items = {'A.', 'B.', 'C.', 'D.'};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = 'A.';
            app.TextArea_3 = uitextarea(app.UIFigure);
            app.TextArea_3.ValueChangedFcn = createCallbackFcn(app, @TextArea_3ValueChanged, true);
            app.TextArea_3.Editable = 'off';
            app.CheckSolutionButton = uibutton(app.UIFigure, 'push');
            app.CheckSolutionButton.ButtonPushedFcn = createCallbackFcn(app, @CheckSolutionButtonPushed, true);
            app.CheckSolutionButton.Text = 'Check Solution';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Click here and then choose the correct answer below by clicking on the correct letter';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = av1(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end