classdef mainScreen < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Button              matlab.ui.control.Button
        EasyButton          matlab.ui.control.Button
        TextArea            matlab.ui.control.TextArea
        MediumButton        matlab.ui.control.Button
        HardButton          matlab.ui.control.Button
        MystatisticsButton  matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EasyButton
        function EasyButtonPushed(app, event)
            clear all;
            app.UIFigure.Visible = 'off';
            %Easy_Problems
            Animation1
        end

        % Button pushed function: MediumButton
        function MediumButtonPushed(app, event)
            clear all;
            app.UIFigure.Visible = 'off';
            %Medium_Problems
            Animation2
        end

        % Button pushed function: HardButton
        function HardButtonPushed(app, event)
            clear all;
            app.UIFigure.Visible = 'off';
            %Hard_Problems
            Animation3
        end

        % Button pushed function: MystatisticsButton
        function MystatisticsButtonPushed(app, event)
            Student_number = evalin('base','Student_number');
            student_stats;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.Icon = 'Euler2a.gif';
            app.Button.Text = '';
            app.EasyButton = uibutton(app.UIFigure, 'push');
            app.EasyButton.ButtonPushedFcn = createCallbackFcn(app, @EasyButtonPushed, true);
            app.EasyButton.Text = 'Easy';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Welcome to this interactive learning platform designed to aid you on your journey throught the wonders of Dynamics and Kinematics. To get started, choose a difficulty below.'};
            app.MediumButton = uibutton(app.UIFigure, 'push');
            app.MediumButton.ButtonPushedFcn = createCallbackFcn(app, @MediumButtonPushed, true);
            app.MediumButton.Text = 'Medium';
            app.HardButton = uibutton(app.UIFigure, 'push');
            app.HardButton.ButtonPushedFcn = createCallbackFcn(app, @HardButtonPushed, true);
            app.HardButton.Text = 'Hard';
            app.MystatisticsButton = uibutton(app.UIFigure, 'push');
            app.MystatisticsButton.ButtonPushedFcn = createCallbackFcn(app, @MystatisticsButtonPushed, true);
            app.MystatisticsButton.Text = 'My statistics';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainScreen

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end